classdef SpikeExtractionTool_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        fileMenu               matlab.ui.container.Menu
        loadMenuBar            matlab.ui.container.Menu
        addVoltageMenuBar      matlab.ui.container.Menu
        clearMenuBar           matlab.ui.container.Menu
        clearSelectedMenuBar   matlab.ui.container.Menu
        clearDifferentMenuBar  matlab.ui.container.Menu
        plotNewFigMenuBar      matlab.ui.container.Menu
        accessVoltageMenuBar   matlab.ui.container.Menu
        saveVoltageMenuBar     matlab.ui.container.Menu
        exitMenuBar            matlab.ui.container.Menu
        helpMenu               matlab.ui.container.Menu
        helpMenuItem           matlab.ui.container.Menu
        aboutMenuItem          matlab.ui.container.Menu
        load_panel             matlab.ui.container.Panel
        load_voltage_button    matlab.ui.control.Button
        select_dataset         matlab.ui.control.Label
        add_voltage_button     matlab.ui.control.Button
        voltage_panel          matlab.ui.container.Panel
        display_voltage        matlab.ui.control.Label
        curr_signal            matlab.ui.control.DropDown
        clear_voltage_button   matlab.ui.control.Button
        tool_panel             matlab.ui.container.Panel
        tools_title            matlab.ui.control.Label
        tool_list              matlab.ui.control.DropDown
        select_tool            matlab.ui.control.Label
        method_list            matlab.ui.control.DropDown
        select_method          matlab.ui.control.Label
        set_tool_params        matlab.ui.control.Button
        run_tool_button        matlab.ui.control.Button
        automatic_params       matlab.ui.control.CheckBox
        param_panel            matlab.ui.container.Panel
        gen_params             matlab.ui.control.Label
        param1                 matlab.ui.control.Label
        val1                   matlab.ui.control.Label
        param2                 matlab.ui.control.Label
        val2                   matlab.ui.control.Label
        param3                 matlab.ui.control.Label
        val3                   matlab.ui.control.Label
        param4                 matlab.ui.control.Label
        val4                   matlab.ui.control.Label
        param5                 matlab.ui.control.Label
        val5                   matlab.ui.control.Label
        param6                 matlab.ui.control.Label
        val6                   matlab.ui.control.Label
        param7                 matlab.ui.control.Label
        val7                   matlab.ui.control.Label
        param8                 matlab.ui.control.Label
        val8                   matlab.ui.control.Label
        save_voltage           matlab.ui.control.Button
        plot_panel             matlab.ui.container.Panel
        axes1                  matlab.ui.control.UIAxes
        voltage_slider         matlab.ui.control.Slider
        voltage_max            matlab.ui.control.EditField
        voltage_min            matlab.ui.control.EditField
        time_slider            matlab.ui.control.Slider
        time_max               matlab.ui.control.EditField
        time_min               matlab.ui.control.EditField
        access_voltage         matlab.ui.control.Button
        new_figure             matlab.ui.control.Button
        scroll_axes_slider     matlab.ui.control.Slider
        toggleZoomButton       matlab.ui.control.Button
    end

    
    methods (Access = private)
        function add_voltage(app, hObject, eventdata, handles)
            last_tseries    = app.data.curr_tseries;
            new_num_tseries = app.data.num_tseries + 1;
            
            % open image files & retrieve matrices
            [app.data, success] = openVoltageFile(app.data);
            
            if success~=1
               str = sprintf('Error opening file, ignoring');
               displayErrorMsg(str);
               return
            end
            % loading new voltage tseries succeeded
            
            % udpate drop down lists - gotta be done for success = 0 or 1 (?)
            set(app.curr_signal, 'String', app.data.tseries_str);
            % set voltage to 1st newly loaded voltage tseries
            set(app.curr_signal, 'Value', new_num_tseries);
            curr_signal_Callback(app.curr_signal, eventdata, handles);
        end
        
        function clearVoltages(app, hObject, eventdata, mainData, vtc)
               % Set the mouse pointer to waiting to know the function is running.
               set(mainData.figure1, 'pointer', 'watch')
               drawnow;
               try
                  vtc = vtc.Value;
                  delete(hObject.Parent);
                  for i = numel(vtc):-1:1
                     mainData = removeVoltage(mainData, vtc(i));
                  end
               catch E
                  % Mouse pointer back to normal.
                  set(mainData.figure1, 'pointer', 'arrow')
                  runtimeErrorHandler(E);
               end
               % Mouse pointer back to normal.
               set(mainData.figure1, 'pointer', 'arrow')
        end
        
        function closeGUI(app, src, evnt)
            diary off
            
            handles  = guidata(src);
            % if here we've got figure handles - check if there's user data, &
            % quite immediately if there isn't
            if isfield(handles, 'data')
               data = app.data;
            else
               return;
            end
            % don't get user confirmation if there's no user data!
            if isfield(handles, 'data') && app.data.num_tseries>0
               % get user confirmation that they want to close the gui
               response = userConfirmation('Do you want to close the GUI?',...
                  'Close Request Function');
               switch response
                  case 'Yes'
                     % do nothing yet
                  case 'No'
                     diary on
                     return;
               end
            end
            
            % if we've opened smr files we need to close the CED library
            % unload the library when finished
            try unloadlibrary ceds64int; catch, end
            
            
            % NOTE: to get a list of all objects matching some criteria, but for
            % guis, need to use findall() instead of findobj() if the handle
            % visibility of the gui is set to 'callback'
            
            % if we're here we're deleting the gui so need to remove it from the
            % other gui's list of handles
            try
               this_gui   = data.guihandles(1);
               other_guis = data.guihandles(2:end);
               for gi=1:length(other_guis)
                  other = other_guis(gi);
                  % make sure handle hasn't been deleted without us knowing somehow
                  if ishandle(other)
                     other_figures = guidata(other);
                     % for some reason guidata can give either figure handle or user data
                     if isfield(other_figures, 'data')
                        other_data = other_figures.data;
                     end
                     other_handles = other_data.guihandles;
                     if ~isempty(other_handles)
                        deleted = ~ishandle(other_handles);
                        other_handles(deleted) = [];
                        tf = eq(this_gui, other_handles);
                        other_handles(tf)  = [];
                        other_data.guihandles = other_handles;
                        guidata(other.figure1, other);
                     end
                  end
               end
            catch ME
            end
            
            % no matter what happens with those goddam m.f. gui's, delete this one
            delete(gcf);
        end
        
        function figure1_CloseRequestFcn(app, hObject, eventdata, handles)
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            diary off % Deactivates the log. Hence nothing else will be added after closing the GUI.
            
            % don't get user confirmation if there's no user data!
            handles = guidata(src);
            if ~isfield(handles, 'data_struct')
               delete(gcf);
               return;
            end
            if app.data.num_tseries == 0
               delete(gcf);
               return;
            end
            response = userConfirmation('Do you want to close the GUI?',...
               'Close Request Function');
            switch response
               case 'Yes'
                  delete(gcf)
               case 'No'
                  diary on % Reactivates the log
                  return
            end
        end
        
        function figure1_WindowButtonDownFcn(app, hObject, eventdata, handles)
            if ~haveUserData(handles), return; end
            
            % see if pointer's in one of the axes
            %     in_fig = isPointerInObject(handles, 'figure1');
            
            %     in_lp = isPointerInObject(handles, 'axes_lateral');
            %     if in_lp  % button press was in lateral axes
            %         move_crosshairs(handles, 'lateral');
            %         return
            %     end
        end
        
        function figure1_WindowButtonMotionFcn(app, hObject, eventdata, handles)
            if ~haveUserData(handles), return; end
            
            %     % see if pointer's in one of the axes
            %     in_cp = isPointerInObject(handles, 'axes_coronal', 'crosshair');
            %     if in_cp, return; end
            %
            %     % we're nowhere special so display pointer as an arrow
            %     set(hObject,'pointer','arrow');
        end
        
        function [tseries, ts_num, type, ts_name] = getCurrentVoltage(app, handles)
            % if try fails then we're doing if for viewVolume_newWindow - get stat
            % from data struct instead
            try
               ts_num  = get(app.curr_signal,'Value');
               tseries = app.data.tseries{ts_num};
               ts_name = app.data.tseries_str{ts_num};
               type    = tseries.type;
            catch
               ts_num  = app.data.curr_tseries;
               tseries = app.data.tseries{ts_num};
               ts_name = app.data.tseries_str{ts_num};
               type    = tseries.type;
            end
        end
        
        function name = getFileName(app, instruct, defVal, maxLength)
            % prompt user for name of volume saved to file (so they're not stupidly
            % long)
            if isempty(defVal)
               defVal = 'image';
            end
            name = inputdlg(instruct, 'Variable name', 1,{defVal});
            if isempty(name) % user's cancelled process
               return
            end
            name = name{1};
            if exist('maxLength','var') && ~isempty(maxLength)
               while length(name)>maxLength
                  displayErrorMsg(['Can''t exceed ' num2str(maxLength) ' chars, truncating']);
                  name = inputdlg(instruct(1:maxLength), 'Variable name', 1,{defVal});
               end
            end
        end
        
        function userids = getUserTemplateDeleteIDs(app, tseries, method_params)
            % user configuration parameters:
            %   ap.merge_templates.user_selection.template_to_merge_with.value = 1;
            %   ap.merge_templates.user_selection.number_of_templates_to_merge.value   = 1;
            userids = [];
            nap     = size( tseries.data, 2 );
            ndelete  = method_params.number_of_templates_to_remove.value;
            % make sure there's enough templates to merge as many as user requested
            if ndelete >= nap
               str = 'You''re trying to delete more templates than you actually have, try again';
               displayErrorMsg(str);
               return;
            end
            
            % get IDs of templates available to merge
            ids = 1:nap;
            list = struct('type','list', 'format','integer', 'style','popupmenu', 'size',0);
            for ii=1:ndelete
               def{ii,1}    = ids( ii );
               descript     = sprintf( 'Delete %d templates');
               name         = sprintf( 'ap%d', ii ); % ids( ii ) );
               units        = 'integer';
               prompt{ii,1} = sprintf( 'Select template ID to remove');
               prompt{ii,2} = name;    % name of struct field for result
               prompt{ii,3} = units;   % units of parameter
            
               % list options
               options      = ids;
               tmp          = list;
               tmp.limits   = [1 length(options)];
               tmp.items    = options;
               formats(ii,1)= tmp;
            
               % need to update default value for lists from being string to
               % being index into list, else inputsdlg has a hissy
               def{ii,1}    = find( def{ii,1} == options );
            end
            other.Resize      = 'on';
            other.WindowStyle = 'normal';
            other.Interpreter = 'tex';
            
            dlg_title = sprintf( 'Select AP templates to remove');
            try
               [userparams, cancel] = inputsdlg(prompt, dlg_title, formats, def, other);
            catch ME
               str = getCatchMEstring( ME, 'Error setting parameters', false );
               displayErrorMsg( 'Error setting parameters, reverting to old values' );
               userids = [];
               runtimeErrorHandler(ME,'ignore');
               return;
            end
            if cancel
               userids = [];
               return;
            end
            
            % replace list indices with template ids
            names = fieldnames (userparams );
            for i=1:ndelete
               % for the list types extract value from index into list
               userparams.( names{i} ) = formats(i).items( userparams.( names{i} ) );
            end
            
            % if any template ids are repeated, delete repeat
            userids = cellfun( @(f) userparams.(f), names );
            if length( unique( userids ) ) ~= length( userids )
               displayErrorMsg( 'Ignoring repeat template IDs' );
            end
            userids = unique( userids );
        end
        
        function userids = getUserTemplateMergeIDs(app, tseries, method_params)
            % user configuration parameters:
            %   ap.merge_templates.user_selection.template_to_merge_with.value = 1;
            %   ap.merge_templates.user_selection.number_of_templates_to_merge.value   = 1;
            userids = [];
            nap     = size( tseries.data, 2 );
            mergeid = method_params.template_to_merge_with.value;
            nmerge  = method_params.number_of_templates_to_merge.value;
            % make sure there's enough templates to merge as many as user requested
            if nmerge >= nap
               str = 'You''re trying to merge more templates than you actually have, try again';
               displayErrorMsg(str);
               return;
            end
            
            % get IDs of templates available to merge
            ids = 1:nap;
            ids( mergeid ) = []; % remove template we're merging into from list
            list = struct('type','list', 'format','integer', 'style','popupmenu', 'size',0);
            for ii=1:nmerge
               def{ii,1}    = ids( ii );
               descript     = sprintf( 'Template number to merge in to template %d', mergeid );
               name         = sprintf( 'ap%d', ii ); % ids( ii ) );
               units        = 'integer';
               prompt{ii,1} = sprintf( 'Select template ID of AP to merge into template %d', mergeid );
               prompt{ii,2} = name;    % name of struct field for result
               prompt{ii,3} = units;   % units of parameter
            
               % list options
               options      = ids;
               tmp          = list;
               tmp.limits   = [1 length(options)];
               tmp.items    = options;
               formats(ii,1)= tmp;
            
               % need to update default value for lists from being string to
               % being index into list, else inputsdlg has a hissy
               def{ii,1}    = find( def{ii,1} == options );
            end
            other.Resize      = 'on';
            other.WindowStyle = 'normal';
            other.Interpreter = 'tex';
            
            dlg_title = sprintf( 'Select AP templates to merge into template %d', mergeid );
            try
               [userparams, cancel] = inputsdlg(prompt, dlg_title, formats, def, other);
            catch ME
               str = getCatchMEstring( ME, 'Error setting parameters', false );
               displayErrorMsg( 'Error setting parameters, reverting to old values' );
               userids = [];
               runtimeErrorHandler(ME,'ignore');
               return;
            end
            if cancel
               userids = [];
               return;
            end
            
            % replace list indices with template ids
            names = fieldnames (userparams );
            for i=1:nmerge
               % for the list types extract value from index into list
               userparams.( names{i} ) = formats(i).items( userparams.( names{i} ) );
            end
            
            % if any template ids are repeated, delete repeat
            userids = cellfun( @(f) userparams.(f), names );
            if length( unique( userids ) ) ~= length( userids )
               displayErrorMsg( 'Ignoring repeat template IDs' );
            end
            userids = unique( userids );
        end
        
        function have = haveUserData(app, handles)
            have = 0;
            % if no timeseries to display don't try moving crosshairs etc
            try
               if app.data.num_tseries==0
                  return;
               end
            catch   % no data_struct (error trying to access it)
               return;
            end
            have = 1;
        end
        
        function inobject = isPointerInObject(app, handles, object, pointer)
            % E.g. position = get(app.voxelValue_top, 'Position');
            estr = ['pos = get(app.' object ', ''Position'');'];
            eval(estr);
            pt   = get(app.figure1, 'CurrentPoint'); % current pointer position
            
            inobject = (pt(1,1)>=pos(1) && pt(1,1)<=(pos(1)+pos(3))) && ...
               (pt(1,2)>=pos(2) && pt(1,2)<=(pos(2)+pos(4)));
            if inobject && exist('pointer', 'var')
               set(app.figure1,'pointer',pointer);
            end
        end
        
        function load_voltage(app, hObject, eventdata, handles)
            last_dir = app.data.last_dir; % keep before we write over it
            old_numtseries = app.data.num_tseries;
            
            % get rid of all previous data
            handles = toggleSETGUIstate(handles,'off');
            app.data.last_dir = last_dir;
            guidata(hObject,handles);  % saves the change to handles
            data    = app.data;    % get user data from gui handle
            
            % open smr, txt, or mat file
            [data, success] = openVoltageFile(data);
            data.last_tseries = 1;
            data.curr_tseries = 1;
            data.last_tool    = 1;
            data.curr_tool    = 1;
            
            if success==0 % if success==0 --> no valid images found or user cancelled
               displayErrorMsg('No valid voltage data found - please reload');
               % don't update handles with the data_struct changes
               return;
            elseif success==-1
               if old_numtseries>0 % user cancelled out of open file dialogue
                  displayErrorMsg('Load voltage cancelled, but old data was removed (sorry!)');
               end
               return;
            end
            % if new data loaded re-enable GUI
            app.data = data;
            handles = toggleSETGUIstate(handles,'on');
            
            guidata(hObject,handles);   % saves the change to handles
            set(app.curr_signal, 'String', data.tseries_str);
            set(app.curr_signal, 'Value',  1);
            guidata(hObject,handles);
            
            % curr_signal_Callback doesn't return handles so we have to save handles
            % manually, then request a fresh copy using guidata
            curr_signal_Callback(app.curr_signal, '', handles);
            guidata(hObject,handles);
        end
        
        function handles = move_crosshairs(app, handles, axis)
            % %     data_struct = app.data_struct; % mem probs - avoid copying
            tseries = getCurrentVoltage(handles);
            maxt    = get(app.time_slider,   'Max');
            maxv    = get(app.voltage_slider,'Max');
            
            switch axis
               case 'lateral'
                  %             % get new crosshair position values
                  %             buttonpnt = get(app.axes_lateral,'CurrentPoint');
                  %             slice_Z   = round(buttonpnt(1,2)); if slice_Z==0, slice_Z=1; end
                  %             slice_Y   = round(buttonpnt(1,1)); if slice_Y==0, slice_Y=1; end
                  %
                  %             % display new slice & update slice/slider values
                  %             if (1 <= slice_Y && slice_Y <= max_Y) && ...
                  %                     (1 <= slice_Z && slice_Z <= max_Z)
                  %                 slice_X = round(str2num(get(app.slice_X, 'String')));
                  %                 % adjust for flipped images
                  %                 slice_Z = max_Z+1-slice_Z;
                  %                 str = sprintf('%3.3g', stat(slice_X, slice_Y, slice_Z));
                  %                 set(app.voxelValue, 'String', str);
                  %                 updateImages(handles, stat, '', slice_Y, slice_Z);
            
               otherwise
                  error('Unknown axes');
            end % end switch axes
        end
        
        function removeToolBarButtons(app)
            removeItems = ([{'Save Figure'}, {'New Figure'}, {'Open File'}, {'Print Figure'}, {'Link Plot'}, {'Open Property Inspector'}, {'Insert Colorbar'}]);
            for i = 1:size(removeItems,2)
               listOfElements = findall(gcf);
               element = findall(listOfElements,'ToolTipString',string(removeItems(i)));
               set(element,'Visible','off');
            end
        end
        
        function varargout = removeVoltage(app, handles, varargin)
            
            if numel(varargin) == 0
               remove_tseries = app.data.curr_tseries;
            else
               remove_tseries = varargin{1}; % In case we are removing a voltage different to the currently active
            end
               tseries_str    = app.data.tseries_str{remove_tseries};
               last_tseries   = app.data.last_tseries;
               num_tseries    = app.data.num_tseries;
            
            % if the last remaining timeseries is being deleted, clear gui
            if num_tseries==1
               handles = toggleSETGUIstate(handles, 'off');
               guidata(app.figure1, handles);
               if numel(varargin) == 1,varargout = {handles};end % When removing multiple voltages at once, we want to update the state of handles for the caller method
               return;
            
               % last time series may be the same as current time series (being removed)
               %  after deleting a timeseries or some such thing, so show something else
            elseif app.data.last_tseries==remove_tseries
               indices     = 1:num_tseries;
               indices(remove_tseries) = [];
               app.data.last_tseries = indices(1);
            end
            % display last tseries before removing this one so we can check if data
            % types are the same etc
            set(app.curr_signal, 'Value', app.data.last_tseries);
            % Update figure & handles structure
            curr_signal_Callback(app.curr_signal, [], handles);
            handles = guidata(app.figure1);
            % curr_signal changes last & current timeseries indices, so change back
            % (we wanted the function to display the previous timeseries, but the
            % user didn't actually select it themselves so don't update current &
            % last timeseries indices)
            app.data.last_tseries = last_tseries;
            app.data.curr_tseries = remove_tseries;
            
            % now do the actual removing
            app.data.used_names = removeStringFromList(app.data.used_names,  tseries_str);
            app.data.tseries(remove_tseries)     = [];
            app.data.tseries_str(remove_tseries) = [];
            app.data.num_tseries              = app.data.num_tseries - 1;
            
            if app.data.num_tseries==0
               handles = toggleGUIstate(handles,'off');
               if numel(varargin) == 1,varargout = {handles};end % When removing multiple voltages at once, we want to update the state of handles for the caller method
               return
            end
            % update our indices for last tseries if necessary
            if app.data.last_tseries == remove_tseries
               app.data.last_tseries = 1;
            elseif app.data.last_tseries > remove_tseries
               app.data.last_tseries = app.data.last_tseries - 1;
            end
            if app.data.last_tseries > length(app.data.tseries_str)
               str = sprintf('last timeseries index (%d) larger than number of strings (%d)',...
                  app.data.last_tseries, length(app.data.tseries_str) );
               displayErrorMsg(str);
               app.data.last_tseries = 1;
            end
            app.data.curr_tseries = app.data.last_tseries;
            set(app.curr_signal, 'String', app.data.tseries_str);
            set(app.curr_signal, 'Value', app.data.last_tseries);
            app.data.curr_tseries = app.data.last_tseries;
            guidata(app.figure1, handles);
            
            if numel(varargin) == 1,varargout = {handles};end % When removing multiple voltages at once, we want to update the state of handles for the caller method
        end
        
        function run_tool(app, hObject, eventdata, handles)
            % Implement the tool using the method & params requested
            tool_list     = get(app.tool_list, 'String');
            tool_num      = get(app.tool_list, 'Value');
            tool          = tool_list{tool_num};
            
            method_list   = get(app.method_list, 'String');
            method_num    = get(app.method_list, 'Value');
            method        = method_list{method_num};
            
            [tseries, ~, type] = getCurrentVoltage(handles);
            method_params = getToolAndMethodParams(app.data.params, type, tool, method);
            
            switch lower(type)
               case 'voltage'
                  % only remember last tool for voltage coz others don't have many options
                  app.data.last_tool = app.data.curr_tool;
                  app.data.curr_tool = get(app.select_tool, 'Value');
                  switch lower(tool)
                     case 'rescale'
                        if ~strcmp('separate',method_params.select_peaks.value)
                           [voltage, Rest] = rescaleVoltage(tseries, method, method_params, app.options);
                        else
                           method_params.select_peaks.value = 'positive';
                           [voltage, Rest, prev_params] = rescaleVoltage(tseries, method, method_params, app.options);
                           voltage = voltage.*heaviside(voltage);
            %                ff = fields(prev_params);
            %                for fi = 1:length(ff)
            %                   method_params.(ff{fi}).value = prev_params.(ff{fi}).value;
            %                end
                           method_params.select_peaks.value = 'negative';
                           [nvoltage, Nest] = rescaleVoltage(tseries, method, method_params, app.options);
                           nvoltage = -nvoltage;
                           nvoltage = nvoltage.*heaviside(nvoltage);
                           voltage = voltage - nvoltage;
                        end
                        if isempty(voltage)
                           return;
                        end
                        new_tseries.type    = 'voltage';
                        new_tseries.data    = voltage;
                        new_tseries.time    = tseries.time;
                        new_tseries.dt      = tseries.dt;
                        new_tseries.params  = method_params;
                        new_tseries.Rest    = Rest;
                        new_tseries.params.tool   = tool;
                        new_tseries.params.method = method;
                        tool_str            = [tseries.name '_' tool];
                        instruct            = ['Creating ' tool_str ': rename?'];
            
            
                     case 'denoise'
                        voltage = denoiseVoltage(tseries, method, method_params);
                        if isempty(voltage)
                           return;
                        end
                        new_tseries.type    ='voltage';
                        new_tseries.data    = voltage;
                        new_tseries.time    = tseries.time;
                        new_tseries.dt      = tseries.dt;
                        new_tseries.params  = method_params;
                        new_tseries.params.tool   = tool;
                        new_tseries.params.method = method;
                        tool_str            = [tseries.name '_' tool];
                        instruct            = ['Creating ' tool_str ': rename?'];
            
                     case 'identify ap templates'
                        % get time series to apply AP templates to
                        [APtemplates, APfamily] = identifyAPs(tseries, method, method_params,  app.options.debugOption);
                        if isempty(APtemplates)
                           return;
                        end
                        new_tseries.type    = 'ap';
                        new_tseries.data    = APtemplates;
                        new_tseries.time    = (1:size(APtemplates,1))'*tseries.dt;
                        new_tseries.dt      = tseries.dt;
                        new_tseries.params  = method_params;
                        new_tseries.params.tool   = tool;
                        new_tseries.params.method = method;
                        new_tseries.APfamily= APfamily;
                        tool_str            = [tseries.name '_APs'];
                        instruct            = ['Creating ' tool_str ': rename?'];
            
                     case 'extract spikes'
                        % allows user to generate spikes directly from voltage
                        % timeseries, by generating AP templates enroute
                        if strcmpi(method,'k means')
                           % Extracting spikes directly from voltage through K-means
                           % clustering. Not using APs
                           [APspikes, APtimes, APfamily] = extractSpikesUsingKmeans(tseries, method_params, app.options.debugOption);
                           if isempty(APspikes)
                              return;
                           end
                           new_tseries.type    = 'spike';
                           new_tseries.data    = APspikes;
                           new_tseries.time    = tseries.time;
                           new_tseries.dt      = tseries.dt;
                           new_tseries.params  = method_params;
                           new_tseries.params.tool   = tool;
                           new_tseries.params.method = method;
                           new_tseries.APfamily= APfamily; % Here the AP templates are the same as the different axons
                           new_tseries.APstimes= APtimes; % record spike times for getting rate later
                           tool_str            = [tseries.name '_spikes'];
                           instruct            = ['Creating ' tool_str ': rename?'];
            
                        else
                           [APtemplates, APfamily]= identifyAPs(tseries, 'threshold', method_params);
                           if isempty(APtemplates)
                              return;
                           end
                           APnumsamples        = cellfun(@(f) size(f,2), APfamily);
                           normAPs             = method_params.normalise_aps.value; % separate for when gen APs separately
                           [APspikes,APtimes]  = extractSpikesUsingTemplates( APtemplates, APnumsamples, tseries, method, method_params, normAPs );
                           new_tseries.type    = 'spike';
                           new_tseries.data    = APspikes;
                           new_tseries.time    = tseries.time;
                           new_tseries.dt      = tseries.dt;
                           new_tseries.params  = method_params;
                           new_tseries.params.tool   = tool;
                           new_tseries.params.method = method;
                           new_tseries.APfamily= APfamily;
                           new_tseries.APstimes= APtimes; % record spike times for getting rate later
                           tool_str            = [tseries.name '_spikes'];
                           instruct            = ['Creating ' tool_str ': rename?'];
                        end
            
                     case 'utilities'
                        new_tseries = voltageUtilities(tseries, method, method_params);
                        if isempty(tseries)
                           return;
                        end
                        new_tseries.type    ='voltage';
                        new_tseries.name    = tseries.name;
                        new_tseries.params  = method_params;
                        new_tseries.params.tool   = tool;
                        new_tseries.params.method = method;
                        tool_str            = [tseries.name '_' method];
                        instruct            = ['Creating ' tool_str ': rename?'];
            
                     otherwise
                        displayErrorMsg('This tool don''t exist, give up');
                        return;
                  end
            
                  %% Action potentials
               case 'ap'
                  switch lower(tool)
                     case 'extract spikes'
                        % if user hasn't set parameters explicitly they wouldn't
                        % have chosen a voltage timeseries to apply the AP templates to
                        if ~isfield(method_params, 'voltage_timeseries')
                           str = 'Set parameters to choose a voltage timeseries to match the AP templates to';
                           displayErrorMsg(str);
                           return;
                        end
                        voltage_name        = method_params.voltage_timeseries;
                        voltage_index       = strcmpi(voltage_name.value, app.data.tseries_str);
                        if ~any(voltage_index)
                           str = 'Voltage timeseries not found, select a different timeseries';
                           displayErrorMsg(str);
                           return;
                        end
                        voltage_tseries     = app.data.tseries{voltage_index};
                        APtemplates         = tseries.data;
                        if isempty(APtemplates)
                           return;
                        end
                        normAPs             = tseries.params.normalise_aps.value; % get from AP generation
                        % get number of samples that each AP template is estimated from
                        APnumsamples        = cellfun(@(f) size(f,2), tseries.APfamily);
                        [APspikes, APstimes]= ...
                           extractSpikesUsingTemplates(APtemplates, APnumsamples, voltage_tseries, method, method_params, normAPs);
                        if isempty(APspikes)
                           return;
                        end
                        new_tseries.type    = 'spike';
                        new_tseries.data    = APspikes;
                        if iscell( APspikes{1} )
                           % if we ran outta memory we stored only spike times,
                           % which meant we lost the time vector, so I saved it in
                           % the fam struct
                           new_tseries.time = APspikes{1}{1}.time;
                        else
                           % gotta be cheeky getting time since we can plot spikes
                           % consecutively to make it easier to see how they change
                           new_tseries.time = voltage_tseries.time( 1:size(APspikes{1}, 1) );
                        end
                        new_tseries.dt      = voltage_tseries.dt;
                        new_tseries.params  = method_params;
                        new_tseries.params.tool   = tool;
                        new_tseries.params.method = method;
                        new_tseries.APstimes= APstimes; % record spike times for getting rate later
                        tool_str            = [tseries.name '_spikes_' method];
                        tool_str            = [tseries.name '_spikes'];
                        instruct            = ['Creating ' tool_str ': rename?'];
            
                     case 'merge templates'
                        % get time series to apply AP templates to
                        new_tseries = mergeAPtemplates( tseries, method, method_params );
                        if isempty(new_tseries)
                           return;
                        end
                        tool_str            = [tseries.name '_APs_' method];
                        tool_str            = [tseries.name '_APs'];
                        instruct            = ['Creating ' tool_str ': rename?'];
            
                     case 'delete templates'
                        % get time series to apply AP templates to
                        new_tseries = deleteAPtemplates( tseries, method, method_params );
                        if isempty(new_tseries)
                           return;
                        end
                        tool_str            = [tseries.name '_APs'];
                        instruct            = ['Creating ' tool_str ': rename?'];
                     otherwise
                        displayErrorMsg('This tool don''t exist, give up');
                        return;
                  end
            
               case 'spike'
                  switch lower(tool)
                     case 'firing rate'
                        [rates, time, dt] = getRateFromSpikes(tseries, method, method_params);
                        if isempty(rates), return; end
                        new_tseries.type = 'rate';
                        new_tseries.data = rates;
                        new_tseries.time = time;
                        new_tseries.dt   = dt;
                        new_tseries.params= method_params;
                        new_tseries.params.tool   = tool;
                        new_tseries.params.method = method;
                        tool_str         = [tseries.name '_rates']; % _' method];
                        tool_str(tool_str=='_') = ' '; % make pretty for dialogue title
                        instruct         = ['Creating ' tool_str ': rename?'];
            
                     case 'statistics'
                        generateSpikeStatistics(tseries, method, method_params);
                        return;
            
                     case 'spike operations'
                        [spikes,stimes] = runSpikeOperations(tseries, method, method_params);
            
                        new_tseries.type    = 'spike';
                        new_tseries.data    = spikes;
                        new_tseries.time    = tseries.time;
                        new_tseries.dt      = tseries.dt;
                        new_tseries.params  = method_params;
                        new_tseries.params.tool   = tool;
                        new_tseries.params.method = method;
                        new_tseries.APstimes= stimes; % record spike times for getting rate later
                        tool_str            = [tseries.name '_spikes_' method];
                        tool_str            = [tseries.name '_spikes'];
                        instruct            = ['Creating ' tool_str ': rename?'];
            
                     otherwise
                  end
            
               case 'rate'
                  % currently the only tool for 'rate' timeseries is generating
                  % statistics, & the only statistic is autocorrelation
                  generateRateStatistics(tseries, method, method_params);
                  return;
            
               otherwise
                  displayErrorMsg('You''re making shit up, this isn''t a data type');
                  return;
            
            end
            
            try
               name = getFileName(instruct, tool_str, 60);
            catch E
               if strcmp('MATLAB:inputdlg:InvalidInput',E.identifier)
                  runtimeErrorHandler(E,'ignore');
                  name = 'voltage';
               end
            end
            
            if isempty(name) % user cancelled process
               return
            end
            
            % if we're here we have a new timeseries - populate nec info & add to gui
            new_tseries.name = name;
            if isfield(tseries, 'params')
               new_tseries.params.nested_params = tseries.params;
            else
               new_tseries.params.nested_params = [];
            end
            
            %% save new tseries in user data & display in gui
            new_numtseries = app.data.num_tseries + 1;
            tseries_str    = app.data.tseries_str;
            used_names     = app.data.used_names;
            [name, used_names] = check4RepeatString(name, used_names);
            tseries_str{new_numtseries} = name;
            app.data.tseries{new_numtseries} = new_tseries;
            app.data.tseries_str = tseries_str;
            app.data.num_tseries = new_numtseries;
            app.data.used_names  = used_names;
            
            set(app.curr_signal, 'String', tseries_str);
            set(app.curr_signal, 'Value',  new_numtseries);
            guidata(app.run_tool_button,handles); % saves the change to handles
            curr_signal_Callback(app.curr_signal, [], handles);
        end
        
        function scroll_axes(app, hObject, eventdata, handles)
            tseries = getCurrentVoltage(handles);
            handles = updateSETFigure(handles, tseries);
            guidata(hObject, handles);
        end
        
        function time_slider_updated(app, hObject, eventdata, handles)
            method = app.toggleZoomButton.UserData; % Loads current option (zoom or displacement)
            
            if strcmp('zoom',method)
               % slider zooms in/out to max/min values given in text boxes, so that
               % slider is a percentage of possible max/mins.
               percent = hObject.Value;
               app.data.zoomPercentage(1) = percent;
               prev_tlim  = app.data.tlim; % record time lims before slider was moved
            
               % calculate new min & max time lims by zoomin in from both ends
               tseries = getCurrentVoltage(handles);
               zoom_min = app.data.tlim(1);
               zoom_max = app.data.tlim(1) + ((tseries.time(end) - tseries.time(1))*(1-percent));
            
               app.data.tlim(1) = max( zoom_min, tseries.time(1) );
               app.data.tlim(2) = min( zoom_max, tseries.time(end) );
            else
               % Displacement
               displacement = hObject.Value;
               app.data.displacementPercentage(1) = displacement;
               prev_tlim  = app.data.tlim; % record time lims before slider was moved
            
               % calculate new min & max time lims by zoomin in from both ends
               tseries = getCurrentVoltage(handles);
               disp_min = displacement * (tseries.time(end) - tseries.time(1));
               disp_max = disp_min + (app.data.tlim(2) - app.data.tlim(1));
            
               if disp_max > tseries.time(end)
                  disp_max = tseries.time(end);
                  disp_min = disp_max - (app.data.tlim(2) - app.data.tlim(1));
               end
            
               if disp_min < tseries.time(1)
                  disp_min = tseries.time(1);
                  disp_max = disp_min + (app.data.tlim(2) - app.data.tlim(1));
               end
            
               app.data.tlim(1) = max( disp_min, tseries.time(1) );
               app.data.tlim(2) = min( disp_max, tseries.time(end) );
            
            end
            
            % Update time (horizontal) text boxes
            app.time_min.String = app.data.tlim(1);
            app.time_max.String = app.data.tlim(2);
            
            tseries = getCurrentVoltage(handles);
            handles = updateSETFigure(handles, tseries);
            
            % if there are any other SET gui's open, update their lims if current
            % time axes limits are the same for both (gui's own handle is always 1st)
            if length(app.data.guihandles) > 1
               for ti=2:length(app.data.guihandles)
                  other_gui     = app.data.guihandles(ti);
                  % if other handle is valid update time if time lims match
                  if ishandle(other_gui)
                     other_handles = guidata(other_gui);
                     other_data    = other_app.data;
                     other_tlim    = other_data.tlim;
                     if all( compareFloats( prev_tlim, other_tlim, 0.1, 'percent' ) )
                        set( other_app.time_slider, 'Value', percent );
                        time_slider_Callback( other_app.time_slider, [], other_handles );
                     end
                     % if handle is invalid the gui's been deleted, so ditch
                  else
                     app.data.guihandles(ti) = [];
                  end
               end
            end
            
            guidata(hObject, handles);
        end
        
        function updateTimeSlider(app, hObject, eventdata, handles)
            
               maxt = app.data.tlim(2);
               mint = app.data.tlim(1);
               currtseries = app.data.curr_tseries;
            
               if strcmp('zoom',app.toggleZoomButton.UserData)
                  app.data.zoomPercentage(1) = 1 - ((maxt-mint) / (app.data.tseries{currtseries}.time(end) - app.data.tseries{currtseries}.time(1)));
                  app.time_slider.Value = app.data.zoomPercentage(1); % Update the position of the slider to represent zoom.
                  app.time_slider.SliderStep =  [0.01 0.1];%max(app.voltage_slider.SliderStep(1) , app.data.displacementPercentage(2)); %  Change the size of the vertical slider indicator to match the value displaced in.
            
               else
                  app.data.zoomPercentage(1) = 1 - ((maxt-mint) / (app.data.tseries{currtseries}.time(end) - app.data.tseries{currtseries}.time(1)));
                  app.data.displacementPercentage(1) = 1 - maxt / app.data.tseries{currtseries}.time(end);
                  app.time_slider.Value = app.data.displacementPercentage(1); % Update the position of the slider to represent displacement.
                  app.time_slider.SliderStep(1) = max(app.time_slider.SliderStep(1) , app.data.zoomPercentage(1));% Change the size of the vertical slider indicator to match the value zoomed in.
                  app.time_slider.SliderStep(1) = 0.1 * app.time_slider.SliderStep(2);
            
               end
        end
        
        function updateVoltageSlider(app, hObject, eventdata, handles)
            
               maxv = app.data.vlim(2);
               minv = app.data.vlim(1);
               currtseries = app.data.curr_tseries;
            
               if strcmp('zoom',app.toggleZoomButton.UserData)
                  try
                     app.data.zoomPercentage(2) = 1 - ((maxv-minv) / (max(app.data.tseries{currtseries}.data) - min(app.data.tseries{currtseries}.data)));
                     app.data.zoomPercentage(2) = max(app.data.zoomPercentage(2), 0); % Prevents the zoom to be less than 0
                  catch E
                     if strcmp('MATLAB:max:wrongInput',E.identifier)
                        app.data.zoomPercentage(2) = 1 - ((maxv-minv) / (max(max(app.data.tseries{currtseries}.data{1}{1}.spikes)) - min(min(app.data.tseries{currtseries}.data{1}{1}.spikes))));
                     else
                        rethrow(E);
                     end
                  end
                  app.voltage_slider.Value = app.data.zoomPercentage(2); % Update the position of the slider to represent zoom.
                  app.voltage_slider.SliderStep =  [0.01 0.1];%max(app.voltage_slider.SliderStep(1) , app.data.displacementPercentage(2)); %  Change the size of the vertical slider indicator to match the value displaced in.
            
               else
                  try
                     app.data.zoomPercentage(2) = 1 - ((maxv-minv) / (max(max(app.data.tseries{currtseries}.data{1}{1}.spikes)) - min(min(app.data.tseries{currtseries}.data{1}{1}.spikes))));
                     app.data.displacementPercentage(2) = 1 - maxv / max(max(app.data.tseries{currtseries}.data{1}{1}.spikes));
                  catch E
                     if strcmp('MATLAB:max:wrongInput',E.identifier)
                        app.data.zoomPercentage(2) = 1 - ((maxv-minv) / (max(app.data.tseries{currtseries}.data) - min(app.data.tseries{currtseries}.data)));
                        app.data.displacementPercentage(2) = 1 - maxv / max(app.data.tseries{currtseries}.data);
                     else
                        rethrow(E);
                     end
                  end
                  app.voltage_slider.Value = app.data.displacementPercentage(2); % Update the position of the slider to represent displacement.
                  app.voltage_slider.SliderStep(2) = max(app.voltage_slider.SliderStep(1) , app.data.zoomPercentage(2));% Change the size of the vertical slider indicator to match the value zoomed in.
                  app.voltage_slider.SliderStep(1) = 0.1 * app.voltage_slider.SliderStep(2);
            
               end
        end
        
        function voltage_slider_updated(app, hObject, eventdata, handles)
            method = app.toggleZoomButton.UserData; % Loads current option (zoom or displacement)
            
            if strcmp('zoom',method)
               % slider zooms in/out to max/min values given in text boxes, so that
               % slider is a percentage of possible max/mins.
               percent = hObject.Value;
               app.data.zoomPercentage(2) = percent;
               prev_vlim  = app.data.vlim; % record time lims before slider was moved
            
               % calculate new min & max time lims by zoomin in from both ends
               tseries = getCurrentVoltage(handles);
               vrange = max(tseries.data) - min(tseries.data);
               zoom_min = mean([app.data.vlim(1) app.data.vlim(2)]) - vrange * (1-percent);
               zoom_max = mean([app.data.vlim(1) app.data.vlim(2)]) + vrange * (1-min(percent,0.999));
            
               app.data.vlim(1) = max( zoom_min, min(tseries.data));
               app.data.vlim(2) = min( zoom_max, max(tseries.data));
            else
               % Displacement
               newdisplacement = hObject.Value;
               displacement = app.data.displacementPercentage(2) - newdisplacement;
               app.data.displacementPercentage(2) = newdisplacement;
               prev_vlim  = app.data.vlim; % record time lims before slider was moved
            
               % calculate new min & max time lims by zoomin in from both ends
               tseries = getCurrentVoltage(handles);
               disp_min = app.data.vlim(1) + displacement;
               disp_max = app.data.vlim(2) + displacement;
            
               app.data.vlim(1) = max( disp_min, min(tseries.data));
               app.data.vlim(2) = min( disp_max, max(tseries.data));
            end
            
            % Update voltage (vertical) text boxes
            app.voltage_min.String = app.data.vlim(1);
            app.voltage_max.String = app.data.vlim(2);
            
            tseries = getCurrentVoltage(handles);
            handles = updateSETFigure(handles, tseries);
            guidata(hObject, handles);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SpikeExtractionTool_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to SpikeExtractionTool (see VARARGIN)
%             
%             % Choose default command line output for SpikeExtractionTool
%             app.output   = hObject;
%             
%             %% Generate user data object to store
%             data.num_tseries = 0;
%             data.tseries     = [];   % initialise cell array of time series
%             data.curr_tseries= [];   % current time series
%             data.tseries_str = [];   % initialise names of time series
%             data.used_names  = [];   % list of names already used by user
%             data.last_tseries= [];   % last time series - restore if current ts deleted
%             data.tlim        = [];   % time limits
%             data.vlim        = [];   % voltage limits
%             data.curr_tool   = [];   % record of user's current tool
%             data.last_tool   = [];   % record of user's last tool
%             data.params      = getDefaultToolParams; % default params for all tools & implementation methods
%             data.guihandles  = hObject;              % copy of all SET gui handles
%             data.zoomPercentage = [0 0]; % Records currently chosen zoom value
%             data.displacementPercentage = [0 0.5]; % Records currently chosen displacement value
%             
%             data.last_dir    = pwd;  % where they opened gui from
%             app.data     = data; % record user data in handle
%             
%             handles = toggleSETGUIstate(handles,'off'); % switch everything off until data's loaded
%             
%             % assign gui a name
%             set(hObject, 'Name', 'Spike Extraction Tool');
%             % set(gcf, 'menubar', 'figure') % turn the figure menu on (so can save etc) % I don't think it is a good idea to have this on, better to make a custom menu bar.
%             
%             
%             % prepare for user confirmation when GUI is closed
%             set(app.figure1, 'CloseRequestFcn', @closeGUI);
%             %     set(gcf, 'WindowScrollWheelFcn', {@figure1_figScroll, handles});
%             removeToolBarButtons();
%             
%             % from property inspector in guide, under WindowScrollWheelFcn
%             % @(hObject,eventdata)SpikeExtractionTool('figure1_WindowScrollWheelFcn',hObject,eventdata,guidata(hObject))
%             
%             % Initialize options for debug and loading window
%             app.options.loadingWindowOn = true;
%             app.options.debugOption = 'semi';
%             app.options.rescaleOption = 'at_end';
%             
%             % Initialize tooltips
%             handles = setTooltips(handles);
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             % UIWAIT makes SpikeExtractionTool wait for user response (see UIRESUME)
%             % uiwait(app.figure1);
%             
%             % Starts logging everything to the log file: 'log_all.log'.
%             % Everything means all the text printed in the command window, except for
%             % the one that is sent by the function 'printMessage'. Use such function
%             % when sending text to the command window that you don't want to be saved
%             % in the log file. i.e. instead of:
%             %                 cprintf('Keyword',str);
%             % try:
%             %                 printMessage('off','Keyword',str);
%             fid = fopen('./log_all.log', 'a'); % Opens log file to append this session's string
%             fprintf(fid, '\n\n-------------- %s @ %s | %s ---------------\n', getenv('Username'),getenv('UserDomain'),datestr(now, 0));
%             fclose(fid); % Close log file
%             diary('log_all.log'); % Activates the diary function, i.e. save all the activity into a file.
        end

        % Menu selected function: aboutMenuItem
        function aboutMenuItem_Callback(app, event)
            % hObject    handle to aboutMenuItem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
               aboutWindow = dialog();
               str = sprintf(['','\n',...
                              'The University of Melbourne','\n',...
                              '','\n',...
                              'Biomedical Engineering','\n',...
                              '','\n',...
                              'Dr. Katie Davey','\n',...
                              'Dr. Martin Stebbing','\n',...
                              'Dr. Artemio Soto Breceda','\n',...
                              '']);
               uicontrol('parent',aboutWindow,'Style','text',...
                     'String', str,'Position',[187,0,187,200],'FontSize',11);
            
               logo = uicontrol('parent',aboutWindow,'Style','pushbutton',...
                     'Position',[187,200,187,187]);
               [x,map]=imread('fig/unimelb.png'); % Load the zoom icon
               I2=imresize(x, [187 187]); % Resize icon
            %    imshow(I2);
               logo.CData = I2; % Assign icon to the button
        end

        % Menu selected function: accessVoltageMenuBar
        function accessVoltageMenuBar_Callback(app, event)
            % hObject    handle to accessVoltageMenuBar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            access_voltage_Callback(hObject, eventdata, handles);
        end

        % Button pushed function: access_voltage
        function access_voltage_Callback(app, event)
            [tseries, ~, ~, ts_name] = getCurrentVoltage(handles);
            assignin('base', title2Str(ts_name,1,1,'_'), tseries);
        end

        % Button pushed function: add_voltage_button
        function add_voltage_button_Callback(app, event)
            mouseWaitingFunction(app.figure1,@add_voltage,hObject,eventdata,handles);
        end

        % Value changed function: automatic_params
        function automatic_params_Callback(app, event)
            % hObject    handle to automatic_params (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
               if hObject.Value
                  app.set_tool_params.Enable = 'off';
                  app.options.auto_params = true;
               else
                  app.set_tool_params.Enable = 'on';
                  app.options.auto_params = false;
               end
               guidata(hObject,handles); % saves changes to handles
        end

        % Menu selected function: clearDifferentMenuBar
        function clearDifferentMenu_Callback(app, event)
            % hObject    handle to clearDifferentMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
               if app.data.num_tseries == 1
                  displayErrorMsg('There is only one voltage loaded.');
                  return
               else
                  % Find position of current mouse
                  get(app.figure1,'Parent');
                  screenHandle = get(app.figure1,'Parent');
                  pos = screenHandle.PointerLocation;
                  mainData = guidata(gcbf);
                  clearVoltageWindow = figure('Name','Clear','MenuBar','none',...
                     'ToolBar','none','Position',[pos(1),pos(2)-266,212,266],...
                     'WindowStyle','modal','Resize','off','DockControls','off');
                  vtc = uicontrol('parent',clearVoltageWindow,'Style','listbox',...
                     'String',mainData.data.tseries_str,'InnerPosition',[14,56,187,155],...
                     'Enable','on','Max',mainData.data.num_tseries,'Tag','vtc');
                  uicontrol('parent',clearVoltageWindow,'Style','text',...
                     'String', 'Select the voltage(s) you want to remove:',...
                     'InnerPosition',[14,211,187,50],'FontSize',11);
                  uicontrol('parent',clearVoltageWindow,'Style','pushbutton',...
                     'String','Clear','Position',[130,15,69,30],'Callback',...
                     @(hObject,eventdata)SpikeExtractionTool('clearVoltages',hObject,eventdata,mainData,vtc));
               end
        end

        % Button pushed function: clear_voltage_button
        function clear_voltage_button_Callback(app, event)
            [tseries, ts_num, data_type, ts_name] = getCurrentVoltage(handles);
            response = userConfirmation(['Delete ' ts_name '?'],...
               'Clear current time series?');
            if strcmp(response,'No')
               return
            end
            
            mouseWaitingFunction(app.figure1,@removeVoltage,handles); % Instead of removeVoltage(handles);
        end

        % Value changed function: curr_signal
        function curr_signal_Callback(app, event)
            if ~haveUserData(handles)
               return;
            end
            % find out what the user has selected to view
            [tseries, ts_num, data_type] = getCurrentVoltage(handles);
            % add current tseries to last viewed tseries before it's overwritten
            app.data.last_tseries    = app.data.curr_tseries;
            % update current tseries to tseries chosen by user
            app.data.curr_tseries    = ts_num;
            
            % if data type of tseries hasn't changed then tool list doesn't need to
            % change, but if we're displaying a new data type then the tool list and
            % available methods for the tool needs to change
            last_tseries = app.data.tseries{app.data.last_tseries};
            last_type    = app.data.tseries{app.data.last_tseries}.type;
            [last_tlim, last_vlim] = getTimeAndVoltageLimits(last_tseries);
            [curr_tlim, curr_vlim] = getTimeAndVoltageLimits(tseries);
            if ~strcmpi(data_type, last_type)
               % only remember last tool for voltage data cuz not many options for the others
               tool_num    = ternaryOp( strcmpi(data_type, 'voltage'), app.data.last_tool, 1);
               if (isempty(tool_num) || tool_num==0), tool_num=1; end
               tool_list   = getSETToolList(data_type);
               tool        = tool_list{tool_num};
               method_list = getSETToolMethodsList(tool, data_type);
               set(app.tool_list,   'String', tool_list);
               set(app.tool_list,   'Value',  tool_num);
               set(app.method_list, 'String', method_list);
               set(app.method_list, 'Value', 1);
            
               %       % update min/max text boxes for voltage & time
               %       app.data.tlim = curr_tlim;
               %       app.data.vlim = curr_vlim;
               %       set(app.voltage_min,'String', num2str(app.data.vlim(1)));
               %       set(app.voltage_max,'String', num2str(app.data.vlim(2)));
               %       set(app.time_min,   'String', num2str(app.data.tlim(1)));
               %       set(app.time_max,   'String', num2str(app.data.tlim(2)));
               %
            end
            
            % update voltage & time sliders - keep them the same if timeseries length
            % is the same as the last viewed tseries
            if ~compareFloats(curr_tlim(2), last_tlim(2), 0.01, 'perc')
               % set time slider and time max/min text boxes from time length of data
               app.data.tlim = curr_tlim;
               mint = app.data.tlim(1); maxt = app.data.tlim(2);
               set(app.time_slider, 'Value',  0);
               set(app.time_max,    'String', sprintf('%.2f',maxt));
               set(app.time_min,    'String', sprintf('%.2f',mint));
            end
            if ~compareFloats(curr_vlim(2), last_vlim(2), 0.01, 'perc')
               % set time slider and time max/min text boxes from scale of data
               app.data.vlim = curr_vlim;
               minv = app.data.vlim(1); maxv = app.data.vlim(2);
            %    set(app.voltage_slider, 'Value', 1);
               set(app.voltage_max,    'String', sprintf('%.2f',maxv));
               set(app.voltage_min,    'String', sprintf('%.2f',minv));
            end
            % udpate figure to show tseries chosen by user
            guidata(hObject,handles); % saves changes to handles
            handles = updateSETFigure(handles, tseries);
            updateGUIParams(handles, tseries);
            
            guidata(hObject,handles); % saves changes to handles
        end

        % Menu selected function: exitMenuBar
        function exitMenuBar_Callback(app, event)
            % hObject    handle to exitMenuBar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            closeGUI(hObject,eventdata);
        end

        % Window scroll wheel function: figure1
        function figure1_WindowScrollWheelFcn(app, event)
            % hObject    handle to figure1 (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	VerticalScrollCount: signed integer indicating direction and number of clicks
            %	VerticalScrollAmount: number of lines scrolled for each click
            % handles    structure with handles and user data (see GUIDATA)
            
            % Mouse pointer back to normal.
            set(app.figure1, 'pointer', 'arrow')
        end

        % Menu selected function: helpMenuItem
        function helpMenuItem_Callback(app, event)
            % hObject    handle to helpMenuItem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
               open('resources\SEThelp.pdf');
        end

        % Button pushed function: load_voltage_button
        function load_voltage_button_Callback(app, event)
            % Warn if there is user data
            okToClear = false;
            if app.data.num_tseries == 0
               okToClear = true;
            end
            % Ask for confirmation
            if ~okToClear
               response = userConfirmation('All the currently loaded data will be cleared. Do you accept?', 'Don''t lose your data.');
               switch response
                  case 'Yes'
                     okToClear = true;
                  case 'No'
                     return
               end
            end
            
            mouseWaitingFunction(app.figure1,@load_voltage,hObject,eventdata,handles);
        end

        % Value changed function: method_list
        function method_list_Callback(app, event)
            % update the tooltips
            tooltip_name = {['method_list_' lower(app.method_list.String{app.method_list.Value})]};
            handles = setTooltips(handles, {'method_list'}, getTooltips(tooltip_name));
        end

        % Button pushed function: new_figure
        function new_figure_Callback(app, event)
            new_gui     = SpikeExtractionTool;
            new_handles = guidata(gcf);
            tseries     = getCurrentVoltage(handles);
            app.data.guihandles = [app.data.guihandles; new_gui.guihandles]; % record new gui in our data struct
            guidata(app.figure1, handles);
            
            %% Generate user data object to store
            new_gui.num_tseries = 1;
            new_gui.tseries     = {tseries};             % initialise cell array of time series
            new_gui.curr_tseries= 1;                     % current time series
            new_gui.tseries_str = {tseries.name};        % initialise names of time series
            new_gui.used_names  = {{tseries.name}, 1};   % list of names already used by user
            new_gui.last_tseries= 1;   % last time series - restore if current ts deleted
            new_gui.tlim        = app.data.tlim;
            new_gui.vlim        = app.data.vlim;
            new_gui.last_tool   = 1;                     % record of user's last tool
            new_gui.params      = getDefaultToolParams;  % default params for all tools & implementation methods
            new_gui.last_dir    = app.data.last_dir; % where they opened gui from
            % copy of all SET gui handles - for each gui, make sure it's own handle is 1st
            new_gui.guihandles  = [new_gui.guihandles; app.data.guihandles(1:end-1)];
            new_app.data    = new_gui;               % record user data in handle
            new_handles         = toggleSETGUIstate(new_handles, 'on'); % switch everything off until data's loaded
            
            % udpate voltage timeseries drop down list
            set(new_app.curr_signal, 'String', new_app.data.tseries_str);
            set(new_app.curr_signal, 'Value', 1);
            
            % plot data that was put in new figure
            curr_signal_Callback( new_app.curr_signal, [], new_handles );
        end

        % Menu selected function: plotNewFigMenuBar
        function plotNewFigMenuBar_Callback(app, event)
            % hObject    handle to plotNewFigMenuBar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            new_figure_Callback(hObject,eventdata,handles);
        end

        % Button pushed function: run_tool_button
        function run_tool_button_Callback(app, event)
            mouseWaitingFunction(app.figure1,@run_tool,hObject,eventdata,handles);
        end

        % Menu selected function: saveVoltageMenuBar
        function saveVoltageMenuBar_Callback(app, event)
            % hObject    handle to saveVoltageMenuBar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            save_voltage_Callback(hObject, eventdata, handles);
        end

        % Button pushed function: save_voltage
        function save_voltage_Callback(app, event)
            [tseries, ~, type, ts_name] = getCurrentVoltage(handles);
            sname = title2Str(ts_name,1,1); % save name - options remove all punctuation
            sname = getFileName('Name of saved variable in mat file ...', sname, 63);
            if isempty(sname)
               return; % user's cancelled and hasn't provided a variable name
            end
            
            var_name = title2Str(ts_name,1,1,'_');
            eval_str = [sname ' = tseries;'];
            eval(eval_str);
            
            displayErrorMsg( 'If you save into an existing smr file please ignore Matlab''s warning that it will be written over (select yes)' );
            
            if strcmpi(type,'voltage')
               filterspec = {'*.mat',  'MAT-files (*.mat)'; ...
                  '*.smr',  'Spike files (*.smr)'; };
            else
               filterspec = {'*.mat'};
            end
            [fname, pname, findex] = uiputfile(filterspec, 'Save data as',...
               fullfile( app.data.last_dir, sname) );
            
            if isequal(fname,0) || isequal(pname,0) || findex==0 % (cancelled)
               return;
            end
            full_name = fullfile(pname, fname);
            if findex==1 % .mat
               eval_str  = ['save(full_name, ''' sname ''', ''-v7.3'');'];
               eval(eval_str);
            
            elseif findex==2 % .smr
               % create a new empty file on disk
               dt = tseries.dt;
               scale = 1; offset = 0;
            
               % if file exists get first free channel to write to
               if exist( full_name, 'file' )
                  [ fhand ]   = CEDS64Open( full_name, 0 ); % 0 for read/write mode (1 for read only)
                  [ channel ] = CEDS64GetFreeChan( fhand );
                  if fhand < 0
                     displayErrorMsg( 'Error opening existing smr file %s (code %d)',...
                        fname, fhand );
                     return;
                  end
            
                  % if file doesn't exist, create it & write to channel 1
               else
                  channel = 1;
                  [ fhand ] = CEDS64Create( full_name, 1, 0 );
                  if fhand < 0
                     displayErrorMsg( 'Error creating smr file %s (code %d)',...
                        fname, fillret );
                     return;
                  end
               end
            
               % set max time resolution of file
               dSecs         = CEDS64TimeBase( fhand, dt ); % sets file time base
               % set channel title & units
               [ok, units]   = CEDS64ChanTitle( fhand, channel, 'voltage' );
               % channel divider: 1/file_tick/channel_divider = sample_rate
               ok            = CEDS64SetWaveChan( fhand, channel, 1, 1, 1/dt );
               % get start time (kinda not nec coz we're not including an offset)
               starttime     = CEDS64SecsToTicks( fhand, 0 );
               % Convert to unit16: user_value = (channel_value) * scale /6553.6 + offset
               voltage       = int16( (tseries.data - offset) * 6553.6 / scale );
               % MUST write to ADC or real wave channel
               fillret       = CEDS64WriteWave( fhand, 1, voltage, starttime );
               ok            = CEDS64ChanComment( fhand, 1, 'Voltage generated in SpikeExtractionTool' );
               if fillret < 0
                  CEDS64ErrorMessage(fillret);
                  displayErrorMsg( 'Error writing to smr file %s (code %d)',...
                     fname, fillret );
                  return;
               end
               [ ok ] = CEDS64Close( fhand );
            end
            
            % Waveform channels hold data items that occur at fixed tick intervals,
            % - The sample interval is a fixed, integral multiple of the file tick.
            %   If the file tick were 1 microsecond, the available sample rates in
            %   the file would be 1000000/n where n is know as the channel divider.
            %   To achieve 500 Hz, with a 1 microsecond tick, the divider would be 2000.
            % - Currently, there are two types of waveform channels: Adc and RealWave.
            %   Adc channel type (code 1)
            %   These channels are designed to be efficient in data file space and store
            %   the data as 16-bit signed integers. You can set a scale and an offset
            %   value to convert these values into real, user units:
            %         user value = (16-bit value) * scale /6553.6 + offset
            %   With a scale of 1.0 and an offset of 0.0, the user values span the range
            %   -5.0 to 4.99985 user units.
            % - You can choose to read these channels as either 16-bit integer values or
            %   as 32-bit floating point values (converted with the scale and offset).
            % - You write these channels as 16-bit integers.
            % RealWave channel type (code 9)
            % - These channels store data as 32-bit floating point values.
            %   They also have a scale and offset that is used if you want to read the
            %   data back as 16-bit integers.
            %   16-bit value = (32-bit floating point value - offset) * 6553.6/scale
            % udpate last dir
            
            app.data.last_dir = pname;
            guidata(hObject,handles);
        end

        % Value changed function: scroll_axes_slider
        function scroll_axes_slider_Callback(app, event)
            mouseWaitingFunction(app.figure1, @scroll_axes, hObject, eventdata, handles);
        end

        % Button pushed function: set_tool_params
        function set_tool_params_Callback(app, event)
            % update curr tool now we're actually interested in this one
            if app.data.curr_tool ~= get(app.tool_list, 'Value')
               app.data.last_tool = app.data.curr_tool;
               app.data.curr_tool = get(app.tool_list, 'Value');
            end
            % for the particular choice of tool + implementation (i.e. method)
            % display the configuration parameters & allow user to set them
            [tseries, ts_num, data_type] = getCurrentVoltage(handles);
            data_type    = tseries.type;
            tool_num     = get(app.tool_list,'Value');
            tool_list    = get(app.tool_list,'String');
            tool         = tool_list{tool_num};
            
            method_num   = get(app.method_list,'Value');
            method_list  = get(app.method_list,'String');
            method       = method_list{method_num};
            
            params       = app.data.params; % current parameter values
            % params = getDefaultToolParams;
            method_params= getToolAndMethodParams(params, data_type, tool, method);
            dlg_name     = [tool ' using ' method];
            
            % if using AP templates for matched filtering need to select voltage
            % timseries to apply it to - add list to params for selection (unless
            % it's applied directly to a voltage timeseries)
            if strcmpi(tool, 'extract spikes')  && strcmpi(method, 'matched filter')
               if ~strcmpi(data_type, 'voltage')
                  types     = getStructFieldFromCell(app.data.tseries, 'type');
                  names     = getStructFieldFromCell(app.data.tseries, 'name');
                  isvoltage = strcmpi('voltage', types);
            
                  if sum(isvoltage)==0
                     str    = 'No voltage time series to extract APs from, have another crack later';
                     displayErrorMsg(str);
                     return;
                  end
                  voltlist  = names(isvoltage); % list of all voltage time series'
            
                  % create a parameter to ask user what voltage timeseries to apply AP templates to
                  if ~isfield(method_params, 'voltage_timeseries')
                     % find indices of all tseries that have type voltage
                     voltage_timeseries.value    = names(find(isvoltage,1,'first'));
                     voltage_timeseries.name     = 'voltage timeseries';
                     voltage_timeseries.descript = 'select voltage timeseries to match APs to';
                     voltage_timeseries.type     = 'list';
                     voltage_timeseries.list     = names(isvoltage);
                     voltage_timeseries.units    = 'timeseries name';
                     method_params.voltage_timeseries = voltage_timeseries;
            
                     % voltage timeseries parameter already exists - check it's still valid
                  else
                     voltage_timeseries = method_params.voltage_timeseries;
                     % lists diff lengths so can't be equal (can't easily compare)
                     if length(voltlist)~=length(voltage_timeseries.list)
                        voltage_timeseries.list = names(isvoltage);
                        voltage_tseries.value   = 1;
                        % lists same length but not same content - leave number choice the same
                     elseif ~all(strcmpi(voltage_timeseries.list, voltlist))
                        voltage_timeseries.list = names(isvoltage);
                     end
                     method_params.voltage_timeseries = voltage_timeseries;
                  end
               end
            end
            
            [method_params, cancel] = requestUserParamConfig(method_params, dlg_name);
            
            % if merging templates, user must select template ID now we know how
            % many they want to merge (can't do simultaneously)
            names  = fieldnames( method_params );
            if ~cancel && any( strcmpi( 'number_of_templates_to_merge', names ) )
               mergeids = getUserTemplateMergeIDs( tseries, method_params );
               if isempty( mergeids )
                  return;
               end
               % add merge ids to tseries datastruct since it isn't actually a user
               % parameter
               tseries.mergeAPs = mergeids;
               app.data.tseries{ ts_num } = tseries;
            elseif ~cancel && any( strcmpi( 'number_of_templates_to_remove', names ) )
               % If deleting templates
               deleteids = getUserTemplateDeleteIDs( tseries, method_params );
               if isempty( deleteids )
                  return;
               end
               % add merge ids to tseries datastruct since it isn't actually a user
               % parameter
               tseries.deleteAPs = deleteids;
               app.data.tseries{ ts_num } = tseries;
            end
            
            params = setToolAndMethodParams(params, method_params, data_type, tool, method);
            app.data.params = params;
            guidata(hObject,handles); % saves changes to handles
        end

        % Value changed function: time_max
        function time_max_Callback(app, event)
            if ~haveUserData(handles)
               return;
            end
            
            % Get new max time & check with tseries time vector that it's within limits.
            tseries    = getCurrentVoltage(handles);
            prev_tlim  = app.data.tlim; % previous user time limits
            data_tlims = getTimeAndVoltageLimits(tseries, 'tlim'); % min/max poss time lims
            mint       = data_tlims(1); maxt = data_tlims(2);
            str        = get(app.time_max, 'String');
            [ok, newt] = checkStringInput(str, 'float', mint, maxt);
            if ~ok
               % users input is dodgy - gotta reverse engineer old text box max from
               % slider value & max display limit (zoom_max)
               % zoom_max   = text_max - (text_max - text_min)*proport;
               % zoom_max   = text_max - (text_max - text_min)*proport;
               percent = get(app.time_slider, 'Value');
               proport = (1-percent)/2; % proportion max time changed by slider percentage
               zoom_max= app.data.tlim(2);
               [~,text_min] = checkStringInput(get(app.time_min, 'String'), 'float');
               text_max= (zoom_max + proport*text_min) / (1-proport);
               displayErrorMsg(sprintf('Time must be between %d & %d', mint, maxt));
               set(app.time_max, 'String', sprintf('%.2f',text_max)); % reset to old val
               return
            end
            % Gotta get min time from other text box because need to reset displayed
            % time lims just in case new max is smaller than the last displayed min
            % (zoom allows you to zoom in from text box mins/maxes)
            [~,mint] = checkStringInput(get(app.time_min, 'String'), 'float'); % we know str is valid
            set(app.time_max, 'String', sprintf('%.2f',newt)); % reset to new val
            % set(app.time_slider, 'Value', 1);
            app.data.tlim(2) = newt;
            app.data.tlim(1) = mint;
            
            % Update time sliders
            updateTimeSlider(hObject,eventdata,handles);
            
            handles = updateSETFigure(handles, tseries);
            
            % if there are any other SET gui's open, update their lims if current
            % time axes limits are the same for both (gui's own handle is always 1st)
            if length(app.data.guihandles) > 1
               invalid = ~ishandle( app.data.guihandles );
               app.data.guihandles( invalid ) = [];
            
               for ti=2:length( app.data.guihandles )
                  other_gui     = app.data.guihandles(ti);
                  % if other handle is valid update time if time lims match
                  if ishandle(other_gui)
                     other_handles = guidata(other_gui);
                     other_data    = other_app.data;
                     other_tlim    = other_data.tlim;
                     if all( compareFloats( prev_tlim, other_tlim, 0.1, 'percent' ) )
                        set(other_app.time_max, 'String', sprintf('%.2f',newt)); % reset to new val
                        % time_max_Callback( other_app.time_max, [], other_handles );
            
                        set(other_app.time_max, 'String', sprintf('%.2f',newt)); % reset to new val
                        set(other_app.time_slider, 'Value', 1);
                        other_app.data.tlim(2) = newt;
                        other_app.data.tlim(1) = mint;
                        othertseries    = getCurrentVoltage(other_handles);
                        other_handles = updateSETFigure(other_handles, othertseries);
            
                     end
            
                     % if we're here the handle has been deleted or something, so ditch
                  else
                     app.data.guihandles(ti) = [];
                  end
               end
            end
            
            guidata(hObject, handles);
        end

        % Value changed function: time_min
        function time_min_Callback(app, event)
            if ~haveUserData(handles)
               return;
            end
            
            % Get new max time & check with tseries time vector that it's within limits.
            prev_tlim = app.data.tlim;
            tseries   = getCurrentVoltage(handles);
            tlim      = getTimeAndVoltageLimits(tseries, 'tlim');
            mint      = tlim(1); maxt = tlim(2);
            str       = get(app.time_min, 'String');
            [ok, newt] = checkStringInput(str, 'float', mint, maxt);
            % if user's put in 0 assume they want the smallest time value, dt
            if strcmp(str,'0')
               newt = mint; ok = true;
               set(app.time_min, 'String', sprintf('%.2f', tseries.dt));
            end
            if ~ok
               % users input is dodgy - gotta reverse engineer old text box min from
               % slider value & min display limit (zoom_min)
               % zoom_min = (text_max - text_min)*proport + text_min;
               % text_min = (zoom_min - text_max*proport) / (1-alpha)
               percent = get(app.time_slider, 'Value');
               proport = (1-percent)/2; % proportion max time changed by slider percentage
               zoom_min= app.data.tlim(1);
               [~,text_max] = checkStringInput(get(app.time_max, 'String'), 'float');
               text_min= (zoom_min - proport*text_max) / (1-proport);
               displayErrorMsg(sprintf('Time must be between %d & %d', mint, maxt));
               set(app.time_min, 'String', sprintf('%.2f',text_min)); % reset to old val
               return
            end
            % Gotta get min time from other text box because need to reset displayed
            % time lims just in case new min is larger than the last displayed max
            % (zoom allows you to zoom in from text box mins/maxes)
            [~,maxt] = checkStringInput(get(app.time_max, 'String'), 'float'); % we know str is valid
            set(app.time_min, 'String', sprintf('%.2f',newt)); % reset to new val
            set(app.time_slider, 'Value', 1);
            app.data.tlim(1) = newt;
            app.data.tlim(2) = maxt;
            
            % Update time sliders
            updateTimeSlider(hObject,eventdata,handles);
            
            handles = updateSETFigure(handles, tseries);
            guidata(hObject, handles);
            
            % if there are any other SET gui's open, update their lims if current
            % time axes limits are the same for both (gui's own handle is always 1st)
            if length(app.data.guihandles) > 1
               % remove any deleted handles
               invalid = ~ishandle( app.data.guihandles );
               app.data.guihandles( invalid ) = [];
               for ti=2:length(app.data.guihandles)
                  other_gui     = app.data.guihandles(ti);
                  other_handles = guidata(other_gui);
                  other_data    = other_app.data;
                  other_tlim    = other_data.tlim;
                  if all( compareFloats( prev_tlim, other_tlim, 0.1, 'percent' ) )
                     set(other_app.time_min, 'String', sprintf('%.2f',newt)); % reset to new val
                     set(other_app.time_slider, 'Value', 1);
                     other_app.data.tlim(2) = maxt;
                     other_app.data.tlim(1) = newt;
                     othertseries    = getCurrentVoltage(other_handles);
                     other_handles = updateSETFigure(other_handles, othertseries);
                  end
               end
            end
        end

        % Value changed function: time_slider
        function time_slider_Callback(app, event)
            if ~haveUserData(handles)
               return;
            end
            mouseWaitingFunction(app.figure1,@time_slider_updated,hObject,eventdata,handles);
        end

        % Button pushed function: toggleZoomButton
        function toggleZoomButton_Callback(app, event)
            % hObject    handle to toggleZoomButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Toggles between arrows (displacement) and magnifier (zoom) icons
               warning('off','MATLAB:imagesci:png:libraryWarning'); % Ignore PNG associated warning
            
               if strcmp('zoom',hObject.UserData)
                  % Displacement function has been selected
                  [x,~]=imread('fig/arrowsIcon.png');% Load the displacement icon
                  I2=imresize(x, [22 22]); % Resize icon
                  hObject.CData = I2; % Assign icon to the button
                  hObject.UserData = 'disp'; % Change state to displacement
                  app.time_slider.Value = app.data.displacementPercentage(1); % Update the position of the slider to represent displacement.
                  app.voltage_slider.Value = app.data.displacementPercentage(2); % Update the position of the slider to represent displacement.
                  app.time_slider.SliderStep(2) = max([app.time_slider.SliderStep(1) 1e-1/app.data.zoomPercentage(1)]); % Change the size of the horizontal slider indicator to match the value zoomed in.
                  app.time_slider.SliderStep(2) = min(1, app.time_slider.SliderStep(2)); % Make sure it is within 0 and 1.
                  app.time_slider.SliderStep(1) = (0.1 * app.time_slider.SliderStep(2));
                  app.voltage_slider.SliderStep(2) = max(app.voltage_slider.SliderStep(1) , app.data.zoomPercentage(2));% Change the size of the vertical slider indicator to match the value zoomed in.
                  app.voltage_slider.SliderStep(1) = 0.1 * app.voltage_slider.SliderStep(2);
               else
                  % Zoom function has been selected
                  [x,~]=imread('fig/magnifierIcon.png');% Load the zoom icon
                  I2=imresize(x, [22 22]); % Resize icon
                  hObject.CData = I2; % Assign icon to the button
                  hObject.UserData = 'zoom'; % Change state to zoom
                  app.time_slider.Value = app.data.zoomPercentage(1); % Update the position of the slider to represent zoom.
                  app.voltage_slider.Value = app.data.zoomPercentage(2); % Update the position of the slider to represent zoom.
                  app.time_slider.SliderStep =  [0.001 0.1];%max(app.time_slider.SliderStep(1) , app.data.displacementPercentage(1)); % Change the size of the horizontal slider indicator to match the value displaced in.
                  app.voltage_slider.SliderStep =  [0.01 0.1];%max(app.voltage_slider.SliderStep(1) , app.data.displacementPercentage(2)); %  Change the size of the vertical slider indicator to match the value displaced in.
               end
        end

        % Value changed function: tool_list
        function tool_list_Callback(app, event)
            % get name of tool chosen and update available methods
            [tseries, ~, data_type] = getCurrentVoltage(handles);
            tool_num  = get(hObject, 'Value');
            tool_name = get(hObject, 'String');
            % get methods list & update on gui, selecting first in list as currmethod
            methods   = getSETToolMethodsList(tool_name{tool_num}, data_type);
            set(app.method_list, 'String', methods);
            set(app.method_list, 'Value', 1);
            
            % update the tooltips
            tooltip_name = {['run_tool_' lower(tool_name{tool_num})]};
            handles = setTooltips(handles, {'run_tool'}, getTooltips(tooltip_name));
            tooltip_name = {['tool_list_' lower(tool_name{tool_num})]};
            handles = setTooltips(handles, {'tool_list'}, getTooltips(tooltip_name));
            
            guidata(hObject,handles); % saves changes to handles
        end

        % Value changed function: voltage_max
        function voltage_max_Callback(app, event)
            if ~haveUserData(handles)
               return;
            end
            
            % Get new max voltage & check with tseries data vector that it's within limits.
            tseries = getCurrentVoltage(handles);
            if isnumeric( tseries.data )
               minv = min(tseries.data)*1.2;
               maxv = max(tseries.data)*1.2;
            elseif iscell( tseries.data )
               try
                  minv = min( cellfun( @(d) min( d(:) ), tseries.data ) );
                  maxv = max( cellfun( @(d) max( d(:) ), tseries.data ) );
               catch
                  % If there is an error, it means the cell structure is a bit more
                  % complicated. Set the min and max based on the first template
                  minv = min(min(tseries.data{1}{1}.spikes)) * 1.2;
                  maxv = max(max(tseries.data{1}{1}.spikes)) * 1.2;
               end
            end
            str     = get(app.voltage_max, 'String');
            [ok, newv] = checkStringInput(str, 'float', minv, maxv);
            if ~ok
               % users input is dodgy - gotta reverse engineer old text box max from
               % slider value & max display limit (zoom_max)
               % zoom_max   = text_max - (text_max - text_min)*proport;
               % zoom_max   = text_max - (text_max - text_min)*proport;
               percent = get(app.voltage_slider, 'Value');
               proport = (1-percent)/2; % proportion max time changed by slider percentage
               zoom_max= app.data.vlim(2);
               [~,text_min] = checkStringInput(get(app.voltage_min, 'String'), 'float');
               text_max= (zoom_max + proport*text_min) / (1-proport);
               displayErrorMsg(sprintf('Voltage must be between %d & %d', minv, maxv));
               set(app.voltage_max, 'String', sprintf('%.2f',text_max)); % reset to old val
               return
            end
            % Gotta get min time from other text box because need to reset displayed
            % lims just in case new max is smaller than the last displayed min
            % (zoom allows you to zoom in from text box mins/maxes)
            [~,minv] = checkStringInput(get(app.voltage_min, 'String'), 'float'); % we know str is valid
            set(app.voltage_max, 'String', sprintf('%.2f',newv)); % reset to new val
            
            app.data.vlim(2) = newv;
            app.data.vlim(1) = minv;
            
            % Update voltage sliders
            updateVoltageSlider(hObject,eventdata,handles);
            
            handles = updateSETFigure(handles, tseries);
            guidata(hObject, handles);
        end

        % Value changed function: voltage_min
        function voltage_min_Callback(app, event)
            if ~haveUserData(handles)
               return;
            end
            
            % Get new max voltage & check with tseries data vector that it's within limits
            tseries = getCurrentVoltage(handles);
            minv    = min(tseries.data)*1.2;
            maxv    = max(tseries.data)*1.2;
            str     = get(app.voltage_min, 'String');
            [ok, newv] = checkStringInput(str, 'float', minv, maxv);
            % if user's put in 0 assume they want the smallest voltage value, dt
            if ~ok
               % users input is dodgy - gotta reverse engineer old text box min from
               % slider value & min display limit (zoom_min)
               % zoom_min = (text_max - text_min)*proport + text_min;
               % text_min = (zoom_min - text_max*proport) / (1-alpha)
               percent = get(app.voltage_slider, 'Value');
               proport = (1-percent)/2; % proportion max voltage changed by slider percentage
               zoom_min= app.data.vlim(1);
               [~,text_max] = checkStringInput(get(app.voltage_max, 'String'), 'float');
               text_min= (zoom_min - proport*text_max) / (1-proport);
               displayErrorMsg(sprintf('Voltage must be between %d & %d', minv, maxv));
               set(app.voltage_min, 'String', sprintf('%.2f',text_min)); % reset to old val
               return
            end
            % Gotta get max voltage from other text box because need to reset displayed
            % voltage lims just in case new max is smaller than the last displayed min
            % (zoom allows you to zoom in from text box mins/maxes)
            [~,maxv] = checkStringInput(get(app.voltage_max, 'String'), 'float'); % we know str is valid
            set(app.voltage_min, 'String', sprintf('%.2f',newv)); % reset to new val
            
            app.data.vlim(1) = newv;
            app.data.vlim(2) = maxv;
            
            % Update voltage sliders
            updateVoltageSlider(hObject,eventdata,handles);
            
            handles = updateSETFigure(handles, tseries);
            guidata(hObject, handles);
        end

        % Value changed function: voltage_slider
        function voltage_slider_Callback(app, event)
            if ~haveUserData(handles)
               return;
            end
            
            mouseWaitingFunction(app.figure1,@voltage_slider_updated,hObject,eventdata,handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Spike Extraction Tool';
            app.figure1.WindowScrollWheelFcn = createCallbackFcn(app, @figure1_WindowScrollWheelFcn, true);
            app.fileMenu = uimenu(app.figure1);
            app.fileMenu.Text = 'File';
            app.loadMenuBar = uimenu(app.fileMenu);
            app.loadMenuBar.Text = 'Load Voltage';
            app.addVoltageMenuBar = uimenu(app.fileMenu);
            app.addVoltageMenuBar.Enable = 'off';
            app.addVoltageMenuBar.Text = 'Add Voltage';
            app.clearMenuBar = uimenu(app.fileMenu);
            app.clearMenuBar.Enable = 'off';
            app.clearMenuBar.Text = 'Clear Voltage';
            app.clearSelectedMenuBar = uimenu(app.clearMenuBar);
            app.clearSelectedMenuBar.Text = 'Clear Selected Voltage';
            app.clearDifferentMenuBar = uimenu(app.clearMenuBar);
            app.clearDifferentMenuBar.MenuSelectedFcn = createCallbackFcn(app, @clearDifferentMenu_Callback, true);
            app.clearDifferentMenuBar.Text = 'Clear a Different Voltage';
            app.plotNewFigMenuBar = uimenu(app.fileMenu);
            app.plotNewFigMenuBar.MenuSelectedFcn = createCallbackFcn(app, @plotNewFigMenuBar_Callback, true);
            app.plotNewFigMenuBar.Separator = 'on';
            app.plotNewFigMenuBar.Accelerator = 'N';
            app.plotNewFigMenuBar.Text = 'Plot in New Figure';
            app.accessVoltageMenuBar = uimenu(app.fileMenu);
            app.accessVoltageMenuBar.MenuSelectedFcn = createCallbackFcn(app, @accessVoltageMenuBar_Callback, true);
            app.accessVoltageMenuBar.Enable = 'off';
            app.accessVoltageMenuBar.Text = 'Access Voltage';
            app.saveVoltageMenuBar = uimenu(app.fileMenu);
            app.saveVoltageMenuBar.MenuSelectedFcn = createCallbackFcn(app, @saveVoltageMenuBar_Callback, true);
            app.saveVoltageMenuBar.Enable = 'off';
            app.saveVoltageMenuBar.Accelerator = 'S';
            app.saveVoltageMenuBar.Text = 'Save Voltage';
            app.exitMenuBar = uimenu(app.fileMenu);
            app.exitMenuBar.MenuSelectedFcn = createCallbackFcn(app, @exitMenuBar_Callback, true);
            app.exitMenuBar.Separator = 'on';
            app.exitMenuBar.Text = 'Exit';
            app.helpMenu = uimenu(app.figure1);
            app.helpMenu.Text = 'Help';
            app.helpMenuItem = uimenu(app.helpMenu);
            app.helpMenuItem.MenuSelectedFcn = createCallbackFcn(app, @helpMenuItem_Callback, true);
            app.helpMenuItem.Text = 'Help';
            app.aboutMenuItem = uimenu(app.helpMenu);
            app.aboutMenuItem.MenuSelectedFcn = createCallbackFcn(app, @aboutMenuItem_Callback, true);
            app.aboutMenuItem.Separator = 'on';
            app.aboutMenuItem.Text = 'About';
            app.load_panel = uipanel(app.figure1);
            app.load_voltage_button = uibutton(app.load_panel, 'push');
            app.load_voltage_button.ButtonPushedFcn = createCallbackFcn(app, @load_voltage_button_Callback, true);
            app.load_voltage_button.Text = 'Load voltage';
            app.select_dataset = uilabel(app.load_panel);
            app.select_dataset.Text = 'Select dataset';
            app.add_voltage_button = uibutton(app.load_panel, 'push');
            app.add_voltage_button.ButtonPushedFcn = createCallbackFcn(app, @add_voltage_button_Callback, true);
            app.add_voltage_button.Text = 'Add voltage';
            app.voltage_panel = uipanel(app.figure1);
            app.display_voltage = uilabel(app.voltage_panel);
            app.display_voltage.Text = 'Display voltage';
            app.curr_signal = uidropdown(app.voltage_panel);
            app.curr_signal.Items = {};
            app.curr_signal.ValueChangedFcn = createCallbackFcn(app, @curr_signal_Callback, true);
            app.curr_signal.Value = {};
            app.clear_voltage_button = uibutton(app.voltage_panel, 'push');
            app.clear_voltage_button.ButtonPushedFcn = createCallbackFcn(app, @clear_voltage_button_Callback, true);
            app.clear_voltage_button.Text = 'Clear voltage';
            app.tool_panel = uipanel(app.figure1);
            app.tools_title = uilabel(app.tool_panel);
            app.tools_title.Text = 'Available tools';
            app.tool_list = uidropdown(app.tool_panel);
            app.tool_list.Items = {'Rescale', 'Denoise', 'Identify AP templates', 'Extract spikes', 'Utilities'};
            app.tool_list.ValueChangedFcn = createCallbackFcn(app, @tool_list_Callback, true);
            app.tool_list.Value = 'Rescale';
            app.select_tool = uilabel(app.tool_panel);
            app.select_tool.Text = 'Select tool';
            app.method_list = uidropdown(app.tool_panel);
            app.method_list.Items = {'Particle filter', 'Recursive least squares', 'Recursive mean', 'Variance'};
            app.method_list.ValueChangedFcn = createCallbackFcn(app, @method_list_Callback, true);
            app.method_list.Value = 'Particle filter';
            app.select_method = uilabel(app.tool_panel);
            app.select_method.Text = 'Select method';
            app.set_tool_params = uibutton(app.tool_panel, 'push');
            app.set_tool_params.ButtonPushedFcn = createCallbackFcn(app, @set_tool_params_Callback, true);
            app.set_tool_params.Text = 'Set parameters';
            app.run_tool_button = uibutton(app.tool_panel, 'push');
            app.run_tool_button.ButtonPushedFcn = createCallbackFcn(app, @run_tool_button_Callback, true);
            app.run_tool_button.Text = 'Run tool';
            app.automatic_params = uicheckbox(app.tool_panel);
            app.automatic_params.ValueChangedFcn = createCallbackFcn(app, @automatic_params_Callback, true);
            app.automatic_params.Tooltip = 'sprintf(''If ticked, the parameters are set autmatically. \nUntick this box to set parameters manually.'')';
            app.automatic_params.Text = 'Automatic parameters';
            app.param_panel = uipanel(app.figure1);
            app.gen_params = uilabel(app.param_panel);
            app.gen_params.Text = 'Generation parameters';
            app.param1 = uilabel(app.param_panel);
            app.param1.Visible = 'off';
            app.param1.Text = 'off';
            app.val1 = uilabel(app.param_panel);
            app.val1.Visible = 'off';
            app.val1.Text = 'off';
            app.param2 = uilabel(app.param_panel);
            app.param2.Visible = 'off';
            app.param2.Text = 'off';
            app.val2 = uilabel(app.param_panel);
            app.val2.Visible = 'off';
            app.val2.Text = 'off';
            app.param3 = uilabel(app.param_panel);
            app.param3.Visible = 'off';
            app.param3.Text = 'off';
            app.val3 = uilabel(app.param_panel);
            app.val3.Visible = 'off';
            app.val3.Text = 'off';
            app.param4 = uilabel(app.param_panel);
            app.param4.Visible = 'off';
            app.param4.Text = 'off';
            app.val4 = uilabel(app.param_panel);
            app.val4.Visible = 'off';
            app.val4.Text = 'off';
            app.param5 = uilabel(app.param_panel);
            app.param5.Visible = 'off';
            app.param5.Text = 'off';
            app.val5 = uilabel(app.param_panel);
            app.val5.Visible = 'off';
            app.val5.Text = 'off';
            app.param6 = uilabel(app.param_panel);
            app.param6.Visible = 'off';
            app.param6.Text = 'off';
            app.val6 = uilabel(app.param_panel);
            app.val6.Visible = 'off';
            app.val6.Text = 'off';
            app.param7 = uilabel(app.param_panel);
            app.param7.Visible = 'off';
            app.param7.Text = 'off';
            app.val7 = uilabel(app.param_panel);
            app.val7.Visible = 'off';
            app.val7.Text = 'off';
            app.param8 = uilabel(app.param_panel);
            app.param8.Visible = 'off';
            app.param8.Text = 'off';
            app.val8 = uilabel(app.param_panel);
            app.val8.Visible = 'off';
            app.val8.Text = 'off';
            app.save_voltage = uibutton(app.figure1, 'push');
            app.save_voltage.ButtonPushedFcn = createCallbackFcn(app, @save_voltage_Callback, true);
            app.save_voltage.Text = 'Save voltage';
            app.plot_panel = uipanel(app.figure1);
            app.axes1 = uiaxes(app.plot_panel);
            app.axes1.XLim = [0.000199999994947575 600.000183105469];
            app.axes1.YLim = [-5 4.578857421875];
            app.voltage_slider = uislider(app.plot_panel);
            app.voltage_slider.Limits = [0 1];
            app.voltage_slider.MajorTicks = [];
            app.voltage_slider.ValueChangedFcn = createCallbackFcn(app, @voltage_slider_Callback, true);
            app.voltage_slider.MinorTicks = [];
            app.voltage_max = uieditfield(app.plot_panel, 'text');
            app.voltage_max.ValueChangedFcn = createCallbackFcn(app, @voltage_max_Callback, true);
            app.voltage_max.Value = '4.5789';
            app.voltage_min = uieditfield(app.plot_panel, 'text');
            app.voltage_min.ValueChangedFcn = createCallbackFcn(app, @voltage_min_Callback, true);
            app.voltage_min.Value = '-5';
            app.time_slider = uislider(app.figure1);
            app.time_slider.Limits = [0 1];
            app.time_slider.MajorTicks = [];
            app.time_slider.ValueChangedFcn = createCallbackFcn(app, @time_slider_Callback, true);
            app.time_slider.MinorTicks = [];
            app.time_max = uieditfield(app.figure1, 'text');
            app.time_max.ValueChangedFcn = createCallbackFcn(app, @time_max_Callback, true);
            app.time_max.Value = '600.0002';
            app.time_min = uieditfield(app.figure1, 'text');
            app.time_min.ValueChangedFcn = createCallbackFcn(app, @time_min_Callback, true);
            app.time_min.Value = '0.0002';
            app.access_voltage = uibutton(app.figure1, 'push');
            app.access_voltage.ButtonPushedFcn = createCallbackFcn(app, @access_voltage_Callback, true);
            app.access_voltage.Text = 'Access voltage';
            app.new_figure = uibutton(app.figure1, 'push');
            app.new_figure.ButtonPushedFcn = createCallbackFcn(app, @new_figure_Callback, true);
            app.new_figure.Text = 'Plot in new figure';
            app.scroll_axes_slider = uislider(app.figure1);
            app.scroll_axes_slider.Limits = [0 1];
            app.scroll_axes_slider.MajorTicks = [];
            app.scroll_axes_slider.ValueChangedFcn = createCallbackFcn(app, @scroll_axes_slider_Callback, true);
            app.scroll_axes_slider.MinorTicks = [];
            app.scroll_axes_slider.Visible = 'off';
            app.scroll_axes_slider.Value = 1;
            app.toggleZoomButton = uibutton(app.figure1, 'push');
            app.toggleZoomButton.ButtonPushedFcn = createCallbackFcn(app, @toggleZoomButton_Callback, true);
            app.toggleZoomButton.Icon = 'toggleZoomButton_image.png';
            app.toggleZoomButton.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeExtractionTool_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @SpikeExtractionTool_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end